classdef covidgraphsapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        WelcometotheNSWCOVID19AnalysisAppLabel  matlab.ui.control.Label
        ToviewtheinteractivegraphsandmodelsclickoneoftheabovetabsLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        TestsAnalysisTab                matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        StartDateDatePickerLabel        matlab.ui.control.Label
        StartDateDatePicker             matlab.ui.control.DatePicker
        EndDateDatePickerLabel          matlab.ui.control.Label
        EndDateDatePicker               matlab.ui.control.DatePicker
        PlotButton                      matlab.ui.control.Button
        CasesAnalysisTab                matlab.ui.container.Tab
        UIAxes_2                        matlab.ui.control.UIAxes
        StartDateDatePicker_2Label      matlab.ui.control.Label
        StartDateDatePicker_2           matlab.ui.control.DatePicker
        EndDateDatePicker_2Label        matlab.ui.control.Label
        EndDateDatePicker_2             matlab.ui.control.DatePicker
        PlotButton_2                    matlab.ui.control.Button
        TestingCategoricalDataFrequencyTab  matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        StartDateDatePicker_3Label      matlab.ui.control.Label
        StartDateDatePicker_3           matlab.ui.control.DatePicker
        EndDateDatePicker_3Label        matlab.ui.control.Label
        EndDateDatePicker_3             matlab.ui.control.DatePicker
        UIAxes2_2                       matlab.ui.control.UIAxes
        PlotButton_3                    matlab.ui.control.Button
        CasesCategoricalDataFreqeuncyTab  matlab.ui.container.Tab
        UIAxes2_3                       matlab.ui.control.UIAxes
        PlotButton_4                    matlab.ui.control.Button
        StartDateDatePicker_4Label      matlab.ui.control.Label
        StartDateDatePicker_4           matlab.ui.control.DatePicker
        EndDateDatePicker_4Label        matlab.ui.control.Label
        EndDateDatePicker_4             matlab.ui.control.DatePicker
        UIAxes2_4                       matlab.ui.control.UIAxes
        COVIDsafeBusinessFreqeuncyTab   matlab.ui.container.Tab
        UIAxes2_5                       matlab.ui.control.UIAxes
        PlotButton_5                    matlab.ui.control.Button
        IndustryDropDown_2Label         matlab.ui.control.Label
        IndustryDropDown_2              matlab.ui.control.DropDown
        SpecificInformationLabel        matlab.ui.control.Label
        Rankingmostfrequentoutof32EditFieldLabel  matlab.ui.control.Label
        Rankingmostfrequentoutof32EditField  matlab.ui.control.NumericEditField
        NoofRecordsEditFieldLabel       matlab.ui.control.Label
        NoofRecordsEditField            matlab.ui.control.NumericEditField
        CasesMapAnalysisTab             matlab.ui.container.Tab
        DateSliderLabel                 matlab.ui.control.Label
        DateSlider                      matlab.ui.control.Slider
        TimeModeButtonGroup             matlab.ui.container.ButtonGroup
        ShowallcasesButton              matlab.ui.control.RadioButton
        ShowtimespecificButton          matlab.ui.control.RadioButton
        CaseLocationsinNswPanel         matlab.ui.container.Panel
        DateDatePickerLabel             matlab.ui.control.Label
        DateDatePicker                  matlab.ui.control.DatePicker
        JanuaryLabel                    matlab.ui.control.Label
        OctoberLabel                    matlab.ui.control.Label
        TestsMapAnalysisTab             matlab.ui.container.Tab
        TimeModeButtonGroup_2           matlab.ui.container.ButtonGroup
        ShowallcasesButton_2            matlab.ui.control.RadioButton
        ShowtimespecificButton_2        matlab.ui.control.RadioButton
        TestLocationsinNswPanel         matlab.ui.container.Panel
        JanuaryLabel_2                  matlab.ui.control.Label
        JulyLabel                       matlab.ui.control.Label
        DateSlider_2Label               matlab.ui.control.Label
        DateSlider_2                    matlab.ui.control.Slider
        DateDatePicker_2Label           matlab.ui.control.Label
        DateDatePicker_2                matlab.ui.control.DatePicker
        COVIDsafeBusinessMapAnalysisTab  matlab.ui.container.Tab
        DisplayModeButtonGroup          matlab.ui.container.ButtonGroup
        ShowallbusinessButton           matlab.ui.control.RadioButton
        ShowindustryspecificButton      matlab.ui.control.RadioButton
        COVIDsafeBusinessLocationsinNswPanel  matlab.ui.container.Panel
        IndustryDropDownLabel           matlab.ui.control.Label
        IndustryDropDown                matlab.ui.control.DropDown
        PredictiveModelTab              matlab.ui.container.Tab
        TestPostcodeEditFieldLabel      matlab.ui.control.Label
        TestPostcodeEditField           matlab.ui.control.NumericEditField
        CasePostcodeEditFieldLabel      matlab.ui.control.Label
        CasePostcodeEditField           matlab.ui.control.NumericEditField
        NoCasesPredictionLabel          matlab.ui.control.Label
        CaseDateDatePickerLabel         matlab.ui.control.Label
        CaseDateDatePicker              matlab.ui.control.DatePicker
        TestDateDatePickerLabel         matlab.ui.control.Label
        TestDateDatePicker              matlab.ui.control.DatePicker
        NoofTestsroundedEditFieldLabel  matlab.ui.control.Label
        NoofTestsroundedEditField       matlab.ui.control.NumericEditField
        NoofCasesroundedEditFieldLabel  matlab.ui.control.Label
        NoofCasesroundedEditField       matlab.ui.control.NumericEditField
        NoTestsPredictionLabel          matlab.ui.control.Label
        PredictButton_3                 matlab.ui.control.Button
        PredictButton_4                 matlab.ui.control.Button
        ForalistofallpossiblepostcodespleaserefertothelivescriptsLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            load testingData.mat
            
            % create a histogram showing frequency of tests and limit it to user specified dates
            histogram(app.UIAxes, testingData.datetime)
            
            xlim(app.UIAxes, [app.StartDateDatePicker.Value, app.EndDateDatePicker.Value])
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            load casesData.mat
            
            % create a histogram showing frequency of cases and limit it to user specified dates
            histogram(app.UIAxes_2, casesData.datetime, 100)
            
            xlim(app.UIAxes_2, [app.StartDateDatePicker_2.Value, app.EndDateDatePicker_2.Value])
        end

        % Value changing function: DateSlider
        function DateSliderValueChanging(app, event)
            load casesData.mat
            
            % take note of the slider value
            changingValue = event.Value;
            
            % change the panel to axes that can graph geocoordinates
            gx = geoaxes(app.CaseLocationsinNswPanel);
            
            % map slider value (0 to 100) to an appropriate serial datenum
            serialdate = interp1([0, 100], [737815, 738092], changingValue);
            
            % convert slider datenum to date and round to nearest day
            date = datetime(serialdate, 'ConvertFrom', 'datenum');
            date = dateshift(date, 'start', 'day');
            
            % show the user selected date
            app.DateDatePicker.Value = date;
            
            % plot all cases if users select to, otherwise plot cases for the specific slider date
            if app.ShowtimespecificButton.Value
                geoscatter(gx, casesData.latitude(casesData.datetime == date), casesData.longitude(casesData.datetime == date), 10, "red", "filled", "o");
                geolimits(gx, [-38 -27],[141 153]);
            elseif app.ShowallcasesButton.Value
                geoscatter(gx, casesData.latitude, casesData.longitude, 10, "red", "filled", "o");
                geolimits(gx, [-39 -25],[139 155]);
            end
        end

        % Value changing function: DateSlider_2
        function DateSlider_2ValueChanging(app, event)
            load testingData.mat

            % take note of the slider value
            changingValue = event.Value;
            
            % change the panel to axes that can graph geocoordinates
            gx = geoaxes(app.TestLocationsinNswPanel);
            
            % map slider value (0 to 100) to an appropriate serial datenum
            serialdate = interp1([0, 100], [737791, 737981], changingValue);
            
            % convert slider datenum to date and round to nearest day
            date = datetime(serialdate, 'ConvertFrom', 'datenum');
            date = dateshift(date, 'start', 'day');
            
            % show the user selected date
            app.DateDatePicker_2.Value = date;
            
            % plot all tests if users select to, otherwise plot tests for the specific slider date
            if app.ShowtimespecificButton_2.Value
                geoscatter(gx, testingData.latitude(testingData.datetime == date), testingData.longitude(testingData.datetime == date), 10, "green", "filled", "o");
                geolimits(gx, [-38 -27],[141 153]);
            elseif app.ShowallcasesButton_2.Value
                geoscatter(gx, testingData.latitude, testingData.longitude, 10, "green", "filled", "o");
                geolimits(gx, [-39 -25],[139 155]);
            end
        end

        % Selection changed function: DisplayModeButtonGroup
        function DisplayModeButtonGroupSelectionChanged(app, event)
            load businessesData.mat
            
            selectedButton = app.DisplayModeButtonGroup.SelectedObject;
            
            % change the panel to axes that can graph geocoordinates
            gx = geoaxes(app.COVIDsafeBusinessLocationsinNswPanel);
            
            % plot all business location if selected, otherwise show industry specific businesses 
            if app.ShowallbusinessButton.Value
                geoscatter(gx, businessesData.latitude(businessesData.business_reported_state == "NSW"), businessesData.longitude(businessesData.business_reported_state == "NSW"), 10, "magenta", "filled", "o");
                geolimits(gx, [-39 -25],[139 155]);
            elseif app.ShowindustryspecificButton.Value
                geoscatter(gx, businessesData.latitude(businessesData.business_reported_state == "NSW" & businessesData.business_reported_industry == app.IndustryDropDown.Value), businessesData.longitude(businessesData.business_reported_state == "NSW" & businessesData.business_reported_industry == app.IndustryDropDown.Value), 10, "magenta", "filled", "o");
                geolimits(gx, [-39 -25],[139 155]);
            end
        end

        % Value changed function: IndustryDropDown
        function IndustryDropDownValueChanged(app, event)
            load businessesData.mat
            
            % change the panel to axes that can graph geocoordinates
            gx = geoaxes(app.COVIDsafeBusinessLocationsinNswPanel);
            
            % plot all business location if selected, otherwise show industry specific businesses 
            if app.ShowallbusinessButton.Value
                geoscatter(gx, businessesData.latitude(businessesData.business_reported_state == "NSW"), businessesData.longitude(businessesData.business_reported_state == "NSW"), 10, "magenta", "filled", "o");
                geolimits(gx, [-39 -25],[139 155]);
            elseif app.ShowindustryspecificButton.Value
                geoscatter(gx, businessesData.latitude(businessesData.business_reported_state == "NSW" & businessesData.business_reported_industry == app.IndustryDropDown.Value), businessesData.longitude(businessesData.business_reported_state == "NSW" & businessesData.business_reported_industry == app.IndustryDropDown.Value), 10, "magenta", "filled", "o");
                geolimits(gx, [-39 -25],[139 155]);
            end
        end

        % Button pushed function: PlotButton_3
        function PlotButton_3Pushed(app, event)
            load testingData.mat
            
            % plot a histogram of tests in NSW locations including records between user specfied dates
            histogram(app.UIAxes2, testingData.lhd_2010_name(testingData.datetime > app.StartDateDatePicker_3.Value & testingData.datetime < app.EndDateDatePicker_3.Value));
            
            % plot a pie chart of the tests including records between user specified dates
            tbl = tabulate(testingData.lhd_2010_name(testingData.datetime > app.StartDateDatePicker_3.Value & testingData.datetime < app.EndDateDatePicker_3.Value));
            pie(app.UIAxes2_2, cell2mat(tbl(:, 2)));
            legend(app.UIAxes2_2, tbl(:, 1), 'Location', 'northeastoutside');
        end

        % Button pushed function: PlotButton_4
        function PlotButton_4Pushed(app, event)
            load casesData.mat
            
            % plot a histogram of cases in NSW locations including records between user specfied dates
            histogram(app.UIAxes2_3, casesData.lhd_2010_name(casesData.datetime > app.StartDateDatePicker_4.Value & casesData.datetime < app.EndDateDatePicker_4.Value));
            
            % plot a pie chart of the cases including records between user specified dates
            tbl = tabulate(casesData.lhd_2010_name(casesData.datetime > app.StartDateDatePicker_4.Value & casesData.datetime < app.EndDateDatePicker_4.Value));
            pie(app.UIAxes2_4, cell2mat(tbl(:, 2)));
            legend(app.UIAxes2_4, tbl(:, 1), 'Location', 'northeastoutside');
        end

        % Button pushed function: PlotButton_5
        function PlotButton_5Pushed(app, event)
            load businessesData.mat
            
            % plot a histogram of business in each industry
            histogram(app.UIAxes2_5, businessesData.business_reported_industry);
        end

        % Value changed function: IndustryDropDown_2
        function IndustryDropDown_2ValueChanged(app, event)
            value = app.IndustryDropDown_2.Value;
            
            load businessesData.mat
            
            % display how many records in the dataset are in the user-specified industry
            % also display ranking of that industry (most frequent)
            [n,bin] = hist(businessesData.business_reported_industry, unique(businessesData.business_reported_industry));
            [~,idx] = sort(-n);
            
            sorted_industries = bin(idx);
            sorted_frequencies = n(idx);
            
            for k = 1:length(sorted_industries)
                disp(k)
                if strcmp(sorted_industries{k}, value)
                    break;
                end
            end
            
            app.NoofRecordsEditField.Value = sorted_frequencies(k);
            app.Rankingmostfrequentoutof32EditField.Value = k;
        end

        % Button pushed function: PredictButton_3
        function PredictButton_3Pushed(app, event)
            load testsModel.mat
            
            % extradt values from date picker
            [yy, mm, dd] = ymd(app.TestDateDatePicker.Value);
            
            % make features table to be used in predictive model
            features = table(app.TestPostcodeEditField.Value, 0, yy, mm, dd);
            features.Properties.VariableNames = {'postcode', 'cases', 'YY', 'MM', 'DD'};
            
            % predict tests for user specified date and postcode and display it
            testpred = testsModel.predictFcn(features);
            
            app.NoofTestsroundedEditField.Value = round(testpred);
        end

        % Button pushed function: PredictButton_4
        function PredictButton_4Pushed(app, event)
            load casesModel.mat
            
            % extract values from date picker
            [yy, mm, dd] = ymd(app.CaseDateDatePicker.Value);
            
            % make features table to be used in predictive model
            features = table(app.CasePostcodeEditField.Value, 0, yy, mm, dd);
            features.Properties.VariableNames = {'postcode', 'cases', 'YY', 'MM', 'DD'};
            
            % predict cases for user specified date and postcode and display it
            casepred = casesModel.predictFcn(features);
            
            app.NoofCasesroundedEditField.Value = round(casepred);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.WelcometotheNSWCOVID19AnalysisAppLabel = uilabel(app.HomeTab);
            app.WelcometotheNSWCOVID19AnalysisAppLabel.Text = 'Welcome to the NSW COVID-19 Analysis App';
            app.ToviewtheinteractivegraphsandmodelsclickoneoftheabovetabsLabel = uilabel(app.HomeTab);
            app.ToviewtheinteractivegraphsandmodelsclickoneoftheabovetabsLabel.Text = 'To view the interactive graphs and models, click one of the above tabs.';
            app.Label = uilabel(app.HomeTab);
            app.Label.Text = 'You will also find a model that will predict cases and tests in NSW based on a specified date and postcode.';
            app.Label_2 = uilabel(app.HomeTab);
            app.Label_2.Text = 'Note that any analysis on tests will only show records from 01/01/2020 to 09/07/2020.';
            app.Label_3 = uilabel(app.HomeTab);
            app.Label_3.Text = 'Any analysis on cases will only show records from 25/01/2020 to 28/10/2020.';
            app.TestsAnalysisTab = uitab(app.TabGroup);
            app.TestsAnalysisTab.Title = 'Tests Analysis';
            app.UIAxes = uiaxes(app.TestsAnalysisTab);
            title(app.UIAxes, 'No. COVID-19 Tests Overtime')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'No. COVID-19 Tests')
            app.StartDateDatePickerLabel = uilabel(app.TestsAnalysisTab);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.TestsAnalysisTab);
            app.StartDateDatePicker.Limits = [datetime([2020 1 1]) datetime([2020 7 9])];
            app.StartDateDatePicker.DisplayFormat = 'dd/MMM/uuuu';
            app.StartDateDatePicker.Value = datetime([2020 1 1]);
            app.EndDateDatePickerLabel = uilabel(app.TestsAnalysisTab);
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.TestsAnalysisTab);
            app.EndDateDatePicker.Limits = [datetime([2020 1 1]) datetime([2020 7 9])];
            app.EndDateDatePicker.DisplayFormat = 'dd/MMM/uuuu';
            app.EndDateDatePicker.Value = datetime([2020 7 9]);
            app.PlotButton = uibutton(app.TestsAnalysisTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.CasesAnalysisTab = uitab(app.TabGroup);
            app.CasesAnalysisTab.Title = 'Cases Analysis';
            app.UIAxes_2 = uiaxes(app.CasesAnalysisTab);
            title(app.UIAxes_2, 'No. COVID-19 Cases Overtime')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'No. COVID-19 Cases')
            app.StartDateDatePicker_2Label = uilabel(app.CasesAnalysisTab);
            app.StartDateDatePicker_2Label.Text = 'Start Date';
            app.StartDateDatePicker_2 = uidatepicker(app.CasesAnalysisTab);
            app.StartDateDatePicker_2.Limits = [datetime([2020 1 25]) datetime([2020 10 28])];
            app.StartDateDatePicker_2.DisplayFormat = 'dd/MMM/uuuu';
            app.StartDateDatePicker_2.Value = datetime([2020 1 25]);
            app.EndDateDatePicker_2Label = uilabel(app.CasesAnalysisTab);
            app.EndDateDatePicker_2Label.Text = 'End Date';
            app.EndDateDatePicker_2 = uidatepicker(app.CasesAnalysisTab);
            app.EndDateDatePicker_2.Limits = [datetime([2020 1 25]) datetime([2020 10 28])];
            app.EndDateDatePicker_2.DisplayFormat = 'dd/MMM/uuuu';
            app.EndDateDatePicker_2.Value = datetime([2020 10 28]);
            app.PlotButton_2 = uibutton(app.CasesAnalysisTab, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.TestingCategoricalDataFrequencyTab = uitab(app.TabGroup);
            app.TestingCategoricalDataFrequencyTab.Title = 'Testing Categorical Data Frequency';
            app.UIAxes2 = uiaxes(app.TestingCategoricalDataFrequencyTab);
            title(app.UIAxes2, 'Freqeuncy of Test Locations')
            xlabel(app.UIAxes2, 'Location')
            ylabel(app.UIAxes2, 'No. Records')
            app.StartDateDatePicker_3Label = uilabel(app.TestingCategoricalDataFrequencyTab);
            app.StartDateDatePicker_3Label.Text = 'Start Date';
            app.StartDateDatePicker_3 = uidatepicker(app.TestingCategoricalDataFrequencyTab);
            app.StartDateDatePicker_3.Limits = [datetime([2020 1 1]) datetime([2020 7 9])];
            app.StartDateDatePicker_3.DisplayFormat = 'dd/MMM/uuuu';
            app.StartDateDatePicker_3.Value = datetime([2020 1 1]);
            app.EndDateDatePicker_3Label = uilabel(app.TestingCategoricalDataFrequencyTab);
            app.EndDateDatePicker_3Label.Text = 'End Date';
            app.EndDateDatePicker_3 = uidatepicker(app.TestingCategoricalDataFrequencyTab);
            app.EndDateDatePicker_3.Limits = [datetime([2020 1 1]) datetime([2020 7 9])];
            app.EndDateDatePicker_3.DisplayFormat = 'dd/MMM/uuuu';
            app.EndDateDatePicker_3.Value = datetime([2020 7 9]);
            app.UIAxes2_2 = uiaxes(app.TestingCategoricalDataFrequencyTab);
            title(app.UIAxes2_2, '')
            xlabel(app.UIAxes2_2, '')
            ylabel(app.UIAxes2_2, '')
            app.PlotButton_3 = uibutton(app.TestingCategoricalDataFrequencyTab, 'push');
            app.PlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_3Pushed, true);
            app.PlotButton_3.Text = 'Plot';
            app.CasesCategoricalDataFreqeuncyTab = uitab(app.TabGroup);
            app.CasesCategoricalDataFreqeuncyTab.Title = 'Cases Categorical Data Freqeuncy';
            app.UIAxes2_3 = uiaxes(app.CasesCategoricalDataFreqeuncyTab);
            title(app.UIAxes2_3, 'Freqeuncy of Test Locations')
            xlabel(app.UIAxes2_3, 'Location')
            ylabel(app.UIAxes2_3, 'No. Records')
            app.PlotButton_4 = uibutton(app.CasesCategoricalDataFreqeuncyTab, 'push');
            app.PlotButton_4.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_4Pushed, true);
            app.PlotButton_4.Text = 'Plot';
            app.StartDateDatePicker_4Label = uilabel(app.CasesCategoricalDataFreqeuncyTab);
            app.StartDateDatePicker_4Label.Text = 'Start Date';
            app.StartDateDatePicker_4 = uidatepicker(app.CasesCategoricalDataFreqeuncyTab);
            app.StartDateDatePicker_4.Limits = [datetime([2020 1 25]) datetime([2020 10 28])];
            app.StartDateDatePicker_4.DisplayFormat = 'dd/MMM/uuuu';
            app.StartDateDatePicker_4.Value = datetime([2020 1 25]);
            app.EndDateDatePicker_4Label = uilabel(app.CasesCategoricalDataFreqeuncyTab);
            app.EndDateDatePicker_4Label.Text = 'End Date';
            app.EndDateDatePicker_4 = uidatepicker(app.CasesCategoricalDataFreqeuncyTab);
            app.EndDateDatePicker_4.Limits = [datetime([2020 1 1]) datetime([2020 10 28])];
            app.EndDateDatePicker_4.DisplayFormat = 'dd/MMM/uuuu';
            app.EndDateDatePicker_4.Value = datetime([2020 10 28]);
            app.UIAxes2_4 = uiaxes(app.CasesCategoricalDataFreqeuncyTab);
            title(app.UIAxes2_4, '')
            xlabel(app.UIAxes2_4, '')
            ylabel(app.UIAxes2_4, '')
            app.COVIDsafeBusinessFreqeuncyTab = uitab(app.TabGroup);
            app.COVIDsafeBusinessFreqeuncyTab.Title = 'COVID-safe Business Freqeuncy';
            app.UIAxes2_5 = uiaxes(app.COVIDsafeBusinessFreqeuncyTab);
            title(app.UIAxes2_5, 'Freqeuncy of Test Locations')
            xlabel(app.UIAxes2_5, 'Industry')
            ylabel(app.UIAxes2_5, 'No. Records')
            app.PlotButton_5 = uibutton(app.COVIDsafeBusinessFreqeuncyTab, 'push');
            app.PlotButton_5.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_5Pushed, true);
            app.PlotButton_5.Text = 'Plot';
            app.IndustryDropDown_2Label = uilabel(app.COVIDsafeBusinessFreqeuncyTab);
            app.IndustryDropDown_2Label.Text = 'Industry';
            app.IndustryDropDown_2 = uidropdown(app.COVIDsafeBusinessFreqeuncyTab);
            app.IndustryDropDown_2.Items = {'Agriculture', 'Arts and entertainment', 'Auctions and open houses', 'Beauty, nail, waxing, tanning and hairdressing salons', 'Betting agencies', 'Caravan and Camping', 'Community centres and Hall', 'Community sport for adults', 'Community sport for kids', 'Construction', 'Drive-In', 'Education & training', 'Food processing', 'Gyms', 'Healthcare', 'Hotels and accommodation', 'Indoor recreation facilities', 'Legal and justice', 'Libraries', 'Museums and galleries', 'Non-food markets', 'Office environment ', 'Other industry', 'Places of worship', 'Pubs, clubs, bars, breweries and casinos', 'Restaurants and Cafes', 'Retail and Grocery', 'Sports, recreation and gyms', 'Tradespeople', 'Transport and freight', 'Weddings and Funerals', 'Zoos, reptile parks and aquariums'};
            app.IndustryDropDown_2.ValueChangedFcn = createCallbackFcn(app, @IndustryDropDown_2ValueChanged, true);
            app.IndustryDropDown_2.Value = 'Agriculture';
            app.SpecificInformationLabel = uilabel(app.COVIDsafeBusinessFreqeuncyTab);
            app.SpecificInformationLabel.Text = 'Specific Information';
            app.Rankingmostfrequentoutof32EditFieldLabel = uilabel(app.COVIDsafeBusinessFreqeuncyTab);
            app.Rankingmostfrequentoutof32EditFieldLabel.Text = 'Ranking (most frequent) out of 32';
            app.Rankingmostfrequentoutof32EditField = uieditfield(app.COVIDsafeBusinessFreqeuncyTab, 'numeric');
            app.NoofRecordsEditFieldLabel = uilabel(app.COVIDsafeBusinessFreqeuncyTab);
            app.NoofRecordsEditFieldLabel.Text = 'No. of Records';
            app.NoofRecordsEditField = uieditfield(app.COVIDsafeBusinessFreqeuncyTab, 'numeric');
            app.CasesMapAnalysisTab = uitab(app.TabGroup);
            app.CasesMapAnalysisTab.Title = 'Cases Map Analysis';
            app.DateSliderLabel = uilabel(app.CasesMapAnalysisTab);
            app.DateSliderLabel.Text = 'Date';
            app.DateSlider = uislider(app.CasesMapAnalysisTab);
            app.DateSlider.MajorTicks = 0;
            app.DateSlider.ValueChangingFcn = createCallbackFcn(app, @DateSliderValueChanging, true);
            app.DateSlider.MinorTicks = 0;
            app.TimeModeButtonGroup = uibuttongroup(app.CasesMapAnalysisTab);
            app.TimeModeButtonGroup.Title = 'Time Mode';
            app.ShowallcasesButton = uiradiobutton(app.TimeModeButtonGroup);
            app.ShowallcasesButton.Text = 'Show all cases';
            app.ShowallcasesButton.Value = true;
            app.ShowtimespecificButton = uiradiobutton(app.TimeModeButtonGroup);
            app.ShowtimespecificButton.Text = 'Show time specific';
            app.CaseLocationsinNswPanel = uipanel(app.CasesMapAnalysisTab);
            app.CaseLocationsinNswPanel.Title = 'Case Locations in Nsw';
            app.DateDatePickerLabel = uilabel(app.CasesMapAnalysisTab);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.CasesMapAnalysisTab);
            app.JanuaryLabel = uilabel(app.CasesMapAnalysisTab);
            app.JanuaryLabel.Text = 'January';
            app.OctoberLabel = uilabel(app.CasesMapAnalysisTab);
            app.OctoberLabel.Text = 'October';
            app.TestsMapAnalysisTab = uitab(app.TabGroup);
            app.TestsMapAnalysisTab.Title = 'Tests Map Analysis';
            app.TimeModeButtonGroup_2 = uibuttongroup(app.TestsMapAnalysisTab);
            app.TimeModeButtonGroup_2.Title = 'Time Mode';
            app.ShowallcasesButton_2 = uiradiobutton(app.TimeModeButtonGroup_2);
            app.ShowallcasesButton_2.Text = 'Show all cases';
            app.ShowallcasesButton_2.Value = true;
            app.ShowtimespecificButton_2 = uiradiobutton(app.TimeModeButtonGroup_2);
            app.ShowtimespecificButton_2.Text = 'Show time specific';
            app.TestLocationsinNswPanel = uipanel(app.TestsMapAnalysisTab);
            app.TestLocationsinNswPanel.Title = 'Test Locations in Nsw';
            app.JanuaryLabel_2 = uilabel(app.TestsMapAnalysisTab);
            app.JanuaryLabel_2.Text = 'January';
            app.JulyLabel = uilabel(app.TestsMapAnalysisTab);
            app.JulyLabel.Text = 'July';
            app.DateSlider_2Label = uilabel(app.TestsMapAnalysisTab);
            app.DateSlider_2Label.Text = 'Date';
            app.DateSlider_2 = uislider(app.TestsMapAnalysisTab);
            app.DateSlider_2.MajorTicks = 0;
            app.DateSlider_2.ValueChangingFcn = createCallbackFcn(app, @DateSlider_2ValueChanging, true);
            app.DateSlider_2.MinorTicks = 0;
            app.DateDatePicker_2Label = uilabel(app.TestsMapAnalysisTab);
            app.DateDatePicker_2Label.Text = 'Date';
            app.DateDatePicker_2 = uidatepicker(app.TestsMapAnalysisTab);
            app.COVIDsafeBusinessMapAnalysisTab = uitab(app.TabGroup);
            app.COVIDsafeBusinessMapAnalysisTab.Title = 'COVID-safe Business Map Analysis';
            app.DisplayModeButtonGroup = uibuttongroup(app.COVIDsafeBusinessMapAnalysisTab);
            app.DisplayModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayModeButtonGroupSelectionChanged, true);
            app.DisplayModeButtonGroup.Title = 'Display Mode';
            app.ShowallbusinessButton = uiradiobutton(app.DisplayModeButtonGroup);
            app.ShowallbusinessButton.Text = 'Show all business';
            app.ShowallbusinessButton.Value = true;
            app.ShowindustryspecificButton = uiradiobutton(app.DisplayModeButtonGroup);
            app.ShowindustryspecificButton.Text = 'Show industry specific';
            app.COVIDsafeBusinessLocationsinNswPanel = uipanel(app.COVIDsafeBusinessMapAnalysisTab);
            app.COVIDsafeBusinessLocationsinNswPanel.Title = 'COVID-safe Business Locations in Nsw';
            app.IndustryDropDownLabel = uilabel(app.COVIDsafeBusinessMapAnalysisTab);
            app.IndustryDropDownLabel.Text = 'Industry';
            app.IndustryDropDown = uidropdown(app.COVIDsafeBusinessMapAnalysisTab);
            app.IndustryDropDown.Items = {'Agriculture', 'Arts and entertainment', 'Auctions and open houses', 'Beauty, nail, waxing, tanning and hairdressing salons', 'Betting agencies ', 'Caravan and Camping ', 'Community centres and Hall ', 'Community sport for adults ', 'Community sport for kids ', 'Construction ', 'Drive-In ', 'Education & training ', 'Food processing ', 'Gyms ', 'Healthcare ', 'Hotels and accommodation ', 'Indoor recreation facilities ', 'Legal and justice ', 'Libraries ', 'Museums and galleries ', 'Non-food markets ', 'Office environment ', 'Other industry ', 'Places of worship ', 'Pubs, clubs, bars, breweries and casinos ', 'Restaurants and Cafes ', 'Retail and Grocery ', 'Sports, recreation and gyms ', 'Tradespeople ', 'Transport and freight ', 'Weddings and Funerals ', 'Zoos, reptile parks and aquariums '};
            app.IndustryDropDown.ValueChangedFcn = createCallbackFcn(app, @IndustryDropDownValueChanged, true);
            app.IndustryDropDown.Value = 'Agriculture';
            app.PredictiveModelTab = uitab(app.TabGroup);
            app.PredictiveModelTab.Title = 'Predictive Model';
            app.TestPostcodeEditFieldLabel = uilabel(app.PredictiveModelTab);
            app.TestPostcodeEditFieldLabel.Text = 'Test Postcode';
            app.TestPostcodeEditField = uieditfield(app.PredictiveModelTab, 'numeric');
            app.CasePostcodeEditFieldLabel = uilabel(app.PredictiveModelTab);
            app.CasePostcodeEditFieldLabel.Text = 'Case Postcode';
            app.CasePostcodeEditField = uieditfield(app.PredictiveModelTab, 'numeric');
            app.NoCasesPredictionLabel = uilabel(app.PredictiveModelTab);
            app.NoCasesPredictionLabel.Text = 'No. Cases Prediction';
            app.CaseDateDatePickerLabel = uilabel(app.PredictiveModelTab);
            app.CaseDateDatePickerLabel.Text = 'Case Date';
            app.CaseDateDatePicker = uidatepicker(app.PredictiveModelTab);
            app.TestDateDatePickerLabel = uilabel(app.PredictiveModelTab);
            app.TestDateDatePickerLabel.Text = 'Test Date';
            app.TestDateDatePicker = uidatepicker(app.PredictiveModelTab);
            app.NoofTestsroundedEditFieldLabel = uilabel(app.PredictiveModelTab);
            app.NoofTestsroundedEditFieldLabel.Text = 'No. of Tests (rounded)';
            app.NoofTestsroundedEditField = uieditfield(app.PredictiveModelTab, 'numeric');
            app.NoofCasesroundedEditFieldLabel = uilabel(app.PredictiveModelTab);
            app.NoofCasesroundedEditFieldLabel.Text = 'No. of Cases (rounded)';
            app.NoofCasesroundedEditField = uieditfield(app.PredictiveModelTab, 'numeric');
            app.NoTestsPredictionLabel = uilabel(app.PredictiveModelTab);
            app.NoTestsPredictionLabel.Text = 'No. Tests Prediction';
            app.PredictButton_3 = uibutton(app.PredictiveModelTab, 'push');
            app.PredictButton_3.ButtonPushedFcn = createCallbackFcn(app, @PredictButton_3Pushed, true);
            app.PredictButton_3.Text = 'Predict';
            app.PredictButton_4 = uibutton(app.PredictiveModelTab, 'push');
            app.PredictButton_4.ButtonPushedFcn = createCallbackFcn(app, @PredictButton_4Pushed, true);
            app.PredictButton_4.Text = 'Predict';
            app.ForalistofallpossiblepostcodespleaserefertothelivescriptsLabel = uilabel(app.PredictiveModelTab);
            app.ForalistofallpossiblepostcodespleaserefertothelivescriptsLabel.Text = 'For a list of all possible postcodes, please refer to the live scripts.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covidgraphsapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end